classdef Cientifica_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        FX991LACWPanel  matlab.ui.container.Panel
        Borrar          matlab.ui.control.Button
        COMA            matlab.ui.control.Button
        IGUAL           matlab.ui.control.Button
        MENOS           matlab.ui.control.Button
        DIVIDIR         matlab.ui.control.Button
        AC              matlab.ui.control.Button
        MULTIPLICAR     matlab.ui.control.Button
        NUEVE           matlab.ui.control.Button
        OCHO            matlab.ui.control.Button
        SIETE           matlab.ui.control.Button
        MAS             matlab.ui.control.Button
        TRES            matlab.ui.control.Button
        SEIS            matlab.ui.control.Button
        PUNTO           matlab.ui.control.Button
        DOS             matlab.ui.control.Button
        CINCO           matlab.ui.control.Button
        CERO            matlab.ui.control.Button
        UNO             matlab.ui.control.Button
        CUATRO          matlab.ui.control.Button
        RAIZCUBICA      matlab.ui.control.Button
        LITERAL         matlab.ui.control.Button
        RAIZ            matlab.ui.control.Button
        RAIZCUAD        matlab.ui.control.Button
        LOGNATURAL      matlab.ui.control.Button
        TANGENTE        matlab.ui.control.Button
        POTENCIACUB     matlab.ui.control.Button
        POTENCIACUAD    matlab.ui.control.Button
        LOGARITMO       matlab.ui.control.Button
        POTENCIA        matlab.ui.control.Button
        PARENABIERTO    matlab.ui.control.Button
        SENO            matlab.ui.control.Button
        COSENO          matlab.ui.control.Button
        PI              matlab.ui.control.Button
        FACTORIAL       matlab.ui.control.Button
        PARENCERRADO    matlab.ui.control.Button
        GRAFICAR        matlab.ui.control.Button
        RESULTADO       matlab.ui.control.Label
        GRAFICA         matlab.ui.control.UIAxes
    end


    properties (Access = private)
    expression = ''; % expresión que se construye con los botones pulsados
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UNO
        function UNOButtonPushed(app, event)
            app.expression = [app.expression '1']; % concatena '1'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta
        end

        % Button pushed function: DOS
        function DOSButtonPushed(app, event)
            app.expression = [app.expression '2']; % concatena '2'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta
        end

        % Button pushed function: TRES
        function TRESButtonPushed(app, event)
            app.expression = [app.expression '3']; % concatena '3'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta
        end

        % Button pushed function: CUATRO
        function CUATROButtonPushed(app, event)
            app.expression = [app.expression '4']; % concatena '4'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta
        end

        % Button pushed function: CINCO
        function CINCOButtonPushed(app, event)
            app.expression = [app.expression '5']; % concatena '5'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta
        end

        % Button pushed function: SEIS
        function SEISButtonPushed(app, event)
            app.expression = [app.expression '6']; % concatena '6'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta
        end

        % Button pushed function: SIETE
        function SIETEButtonPushed(app, event)
            app.expression = [app.expression '7']; % concatena '7'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta            
        end

        % Button pushed function: OCHO
        function OCHOButtonPushed(app, event)
            app.expression = [app.expression '8']; % concatena '8'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta            
        end

        % Button pushed function: NUEVE
        function NUEVEButtonPushed(app, event)
            app.expression = [app.expression '9']; % concatena '9'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta            
        end

        % Button pushed function: CERO
        function CEROButtonPushed(app, event)
            app.expression = [app.expression '0']; % concatena '0'
            app.RESULTADO.Text = app.expression;  % muestra en la etiqueta
        end

        % Button pushed function: MAS
        function MASButtonPushed(app, event)
            % Evitar concatenar operadores múltiples seguidos
            if ~isempty(app.expression) && ~ismember(app.expression(end), '+-*/')
                app.expression = [app.expression '+'];
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: MENOS
        function MENOSButtonPushed(app, event)
            if ~isempty(app.expression) && ~ismember(app.expression(end), '+-*/')
                app.expression = [app.expression '-'];
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: DIVIDIR
        function DIVIDIRButtonPushed(app, event)
            if ~isempty(app.expression) && ~ismember(app.expression(end), '+-*/')
                app.expression = [app.expression '/'];
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: MULTIPLICAR
        function MULTIPLICARButtonPushed(app, event)
            if ~isempty(app.expression) && ~ismember(app.expression(end), '+-*/')
                app.expression = [app.expression '*'];
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: IGUAL
        function IGUALButtonPushed(app, event)
            try
                % Evalúa la expresión construida
                result = eval(app.expression);
                app.RESULTADO.Text = num2str(result);
                % Opcional: actualizar la expresión con el resultado para seguir calculando
                app.expression = num2str(result);
            catch
                app.RESULTADO.Text = 'Error en expresión';
                app.expression = '';
            end
        end

        % Button pushed function: TANGENTE
        function TANGENTEButtonPushed(app, event)
            app.expression = [app.expression 'tan(deg2rad('];
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: COSENO
        function COSENOButtonPushed(app, event)
            app.expression = [app.expression 'cos(deg2rad('];
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: SENO
        function SENOButtonPushed(app, event)
            app.expression = [app.expression 'sin(deg2rad('];
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: PARENABIERTO
        function PARENABIERTOButtonPushed(app, event)
            app.expression = [app.expression '(']; % concatena paréntesis abierto
            app.RESULTADO.Text = app.expression;   % muestra en pantalla
        end

        % Button pushed function: PARENCERRADO
        function PARENCERRADOButtonPushed(app, event)
            app.expression = [app.expression ')']; % concatena paréntesis cerrado
            app.RESULTADO.Text = app.expression;   % muestra en pantalla
        end

        % Button pushed function: LOGNATURAL
        function LOGNATURALButtonPushed(app, event)
            % log(x) representa el logaritmo natural
            app.expression = [app.expression 'log('];
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: LOGARITMO
        function LOGARITMOButtonPushed(app, event)
            % log10(x) representa el logaritmo decimal
            app.expression = [app.expression 'log10('];
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: POTENCIA
        function POTENCIAButtonPushed(app, event)
            if ~isempty(app.expression) && ~ismember(app.expression(end), '+-*/^')
                app.expression = [app.expression '^'];
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: POTENCIACUB
        function POTENCIACUBButtonPushed(app, event)
            if ~isempty(app.expression) && isstrprop(app.expression(end), 'digit')
                app.expression = [app.expression '^3'];
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: POTENCIACUAD
        function POTENCIACUADButtonPushed(app, event)
            if ~isempty(app.expression) && isstrprop(app.expression(end), 'digit')
                app.expression = [app.expression '^2'];
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: RAIZCUAD
        function RAIZCUADButtonPushed(app, event)
            app.expression = [app.expression 'sqrt('];
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: RAIZCUBICA
        function RAIZCUBICAButtonPushed(app, event)
            if ~isempty(app.expression) && isstrprop(app.expression(end), 'digit')
                app.expression = ['nthroot(' app.expression ',3)'];
            else
                app.expression = [app.expression 'nthroot( ,3)'];
            end
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: RAIZ
        function RAIZButtonPushed(app, event)
            % Formato: nthroot(x, n), el usuario debe ingresar ambos valores
            app.expression = [app.expression 'nthroot('];
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: COMA
        function COMAButtonPushed(app, event)
            if ~isempty(app.expression)
                ultimo = app.expression(end);
                if isstrprop(ultimo, 'digit') || ultimo == ')'
                    app.expression = [app.expression ','];
                end
            end
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: PUNTO
        function PUNTOButtonPushed(app, event)
            if isempty(app.expression)
                % Si está vacío, inicia con "0."
                app.expression = '0.';
            else
                % Buscar la última parte del número actual
                parts = regexp(app.expression, '[\+\-\*/\(\)]', 'split');
                lastPart = parts{end};
        
                % Si la parte actual no contiene ya un punto, permitir agregarlo
                if ~contains(lastPart, '.')
                    app.expression = [app.expression '.'];
                end
            end
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: AC
        function ACButtonPushed(app, event)
            app.expression = '';              % Borra la expresión
            app.RESULTADO.Text = '';          % Borra la visualización
        end

        % Button pushed function: Borrar
        function BorrarButtonPushed(app, event)
            if ~isempty(app.expression)
                app.expression = app.expression(1:end-1);  % Borra último carácter
                app.RESULTADO.Text = app.expression;
            end
        end

        % Button pushed function: FACTORIAL
        function FACTORIALButtonPushed(app, event)
            expr = app.expression;
            
            if isempty(expr)
                return;
            end
        
            % Buscar la última secuencia de dígitos (posible número)
            tokens = regexp(expr, '\d+\.?\d*$', 'match');
            
            if ~isempty(tokens)
                numero = tokens{1};
                % Validar que sea entero y ≥ 0
                valor = str2double(numero);
                if mod(valor,1) ~= 0 || valor < 0
                    app.RESULTADO.Text = 'Solo enteros ≥ 0';
                    return;
                end
                % Reemplazar el número por factorial(numero)
                nuevaExpr = regexprep(expr, '\d+\.?\d*$', ['factorial(' numero ')']);
                app.expression = nuevaExpr;
                app.RESULTADO.Text = app.expression;
            else
                app.RESULTADO.Text = 'Error: expresión inválida';
            end
        end

        % Button pushed function: PI
        function PIButtonPushed(app, event)
            % Si el último carácter es un dígito o paréntesis cerrado, agregar un '*'
            if ~isempty(app.expression)
                ultimo = app.expression(end);
                if isstrprop(ultimo, 'digit') || ultimo == ')'
                    app.expression = [app.expression '*pi'];
                else
                    app.expression = [app.expression 'pi'];
                end
            else
                app.expression = 'pi';
            end
            app.RESULTADO.Text = app.expression;
        end

        % Button pushed function: GRAFICAR
        function GRAFICARButtonPushed(app, event)
            expr = app.expression;
        
            if isempty(expr)
                app.RESULTADO.Text = 'Ingrese expresión para graficar';
                return;
            end
        
            if ~contains(expr, 'x')
                app.RESULTADO.Text = 'La expresión debe contener la variable x';
                return;
            end
        
            x = linspace(-10, 10, 400);
        
            try
                % CORRECCIÓN CLAVE: usar potencia elemento a elemento
                expr = strrep(expr, '^', '.^');
        
                y = eval(expr); 
                
                if ~isvector(y) || length(y) ~= length(x)
                    error('La expresión no devuelve un vector del mismo tamaño que x');
                end
        
                plot(app.GRAFICA, x, y, 'LineWidth', 2);
                grid(app.GRAFICA, 'on');
                title(app.GRAFICA, ['Gráfica de y = ' expr]);
                xlabel(app.GRAFICA, 'x');
                ylabel(app.GRAFICA, 'y');
        
                app.RESULTADO.Text = 'Gráfica generada';
            catch ME
                app.RESULTADO.Text = ['Error al graficar: ' ME.message];
            end
        end

        % Button pushed function: LITERAL
        function LITERALButtonPushed(app, event)
    literal = 'x';  % Única variable permitida
    
    % Si el último caracter es dígito o paréntesis cerrado, agregar '*'
    if ~isempty(app.expression)
        ultimo = app.expression(end);
        if isstrprop(ultimo, 'digit') || ultimo == ')'
            app.expression = [app.expression '*' literal];
        else
            app.expression = [app.expression literal];
        end
    else
        app.expression = literal;
    end

    app.RESULTADO.Text = app.expression;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CALCULADORA CIENTIFICA';
            app.figure1.Tag = 'figure1';
            app.GRAFICA = uiaxes(app.figure1);
            app.GRAFICA.Tag = 'GRAFICA';
            app.FX991LACWPanel = uipanel(app.figure1);
            app.FX991LACWPanel.Title = 'FX-991LACW';
            app.RESULTADO = uilabel(app.FX991LACWPanel);
            app.RESULTADO.Tag = 'RESULTADO';
            app.RESULTADO.Text = '';
            app.GRAFICAR = uibutton(app.FX991LACWPanel, 'push');
            app.GRAFICAR.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButtonPushed, true);
            app.GRAFICAR.Tag = 'GRAFICAR';
            app.GRAFICAR.Text = 'Graficar';
            app.PARENCERRADO = uibutton(app.FX991LACWPanel, 'push');
            app.PARENCERRADO.ButtonPushedFcn = createCallbackFcn(app, @PARENCERRADOButtonPushed, true);
            app.PARENCERRADO.Tag = 'PARENCERRADO';
            app.PARENCERRADO.Text = ')';
            app.FACTORIAL = uibutton(app.FX991LACWPanel, 'push');
            app.FACTORIAL.ButtonPushedFcn = createCallbackFcn(app, @FACTORIALButtonPushed, true);
            app.FACTORIAL.Tag = 'FACTORIAL';
            app.FACTORIAL.Text = 'x!';
            app.PI = uibutton(app.FX991LACWPanel, 'push');
            app.PI.ButtonPushedFcn = createCallbackFcn(app, @PIButtonPushed, true);
            app.PI.Tag = 'PI';
            app.PI.Text = 'π';
            app.COSENO = uibutton(app.FX991LACWPanel, 'push');
            app.COSENO.ButtonPushedFcn = createCallbackFcn(app, @COSENOButtonPushed, true);
            app.COSENO.Tag = 'COSENO';
            app.COSENO.Text = 'cos';
            app.SENO = uibutton(app.FX991LACWPanel, 'push');
            app.SENO.ButtonPushedFcn = createCallbackFcn(app, @SENOButtonPushed, true);
            app.SENO.Tag = 'SENO';
            app.SENO.Text = 'sin';
            app.PARENABIERTO = uibutton(app.FX991LACWPanel, 'push');
            app.PARENABIERTO.ButtonPushedFcn = createCallbackFcn(app, @PARENABIERTOButtonPushed, true);
            app.PARENABIERTO.Tag = 'PARENABIERTO';
            app.PARENABIERTO.Text = '(';
            app.POTENCIA = uibutton(app.FX991LACWPanel, 'push');
            app.POTENCIA.ButtonPushedFcn = createCallbackFcn(app, @POTENCIAButtonPushed, true);
            app.POTENCIA.Tag = 'POTENCIA';
            app.POTENCIA.Text = '^';
            app.LOGARITMO = uibutton(app.FX991LACWPanel, 'push');
            app.LOGARITMO.ButtonPushedFcn = createCallbackFcn(app, @LOGARITMOButtonPushed, true);
            app.LOGARITMO.Tag = 'LOGARITMO';
            app.LOGARITMO.Text = 'log';
            app.POTENCIACUAD = uibutton(app.FX991LACWPanel, 'push');
            app.POTENCIACUAD.ButtonPushedFcn = createCallbackFcn(app, @POTENCIACUADButtonPushed, true);
            app.POTENCIACUAD.Tag = 'POTENCIACUAD';
            app.POTENCIACUAD.Text = 'x²';
            app.POTENCIACUB = uibutton(app.FX991LACWPanel, 'push');
            app.POTENCIACUB.ButtonPushedFcn = createCallbackFcn(app, @POTENCIACUBButtonPushed, true);
            app.POTENCIACUB.Tag = 'POTENCIACUB';
            app.POTENCIACUB.Text = 'x³';
            app.TANGENTE = uibutton(app.FX991LACWPanel, 'push');
            app.TANGENTE.ButtonPushedFcn = createCallbackFcn(app, @TANGENTEButtonPushed, true);
            app.TANGENTE.Tag = 'TANGENTE';
            app.TANGENTE.Text = 'tan';
            app.LOGNATURAL = uibutton(app.FX991LACWPanel, 'push');
            app.LOGNATURAL.ButtonPushedFcn = createCallbackFcn(app, @LOGNATURALButtonPushed, true);
            app.LOGNATURAL.Tag = 'LOGNATURAL';
            app.LOGNATURAL.Text = 'ln';
            app.RAIZCUAD = uibutton(app.FX991LACWPanel, 'push');
            app.RAIZCUAD.ButtonPushedFcn = createCallbackFcn(app, @RAIZCUADButtonPushed, true);
            app.RAIZCUAD.Text = '²√';
            app.RAIZ = uibutton(app.FX991LACWPanel, 'push');
            app.RAIZ.ButtonPushedFcn = createCallbackFcn(app, @RAIZButtonPushed, true);
            app.RAIZ.Tag = 'RAIZ';
            app.RAIZ.Text = 'x√';
            app.LITERAL = uibutton(app.FX991LACWPanel, 'push');
            app.LITERAL.ButtonPushedFcn = createCallbackFcn(app, @LITERALButtonPushed, true);
            app.LITERAL.Tag = 'LITERAL';
            app.LITERAL.Text = 'X';
            app.RAIZCUBICA = uibutton(app.FX991LACWPanel, 'push');
            app.RAIZCUBICA.ButtonPushedFcn = createCallbackFcn(app, @RAIZCUBICAButtonPushed, true);
            app.RAIZCUBICA.Tag = 'RAIZCUBICA';
            app.RAIZCUBICA.Text = '³√';
            app.CUATRO = uibutton(app.FX991LACWPanel, 'push');
            app.CUATRO.ButtonPushedFcn = createCallbackFcn(app, @CUATROButtonPushed, true);
            app.CUATRO.Tag = 'CUATRO';
            app.CUATRO.Text = '4';
            app.UNO = uibutton(app.FX991LACWPanel, 'push');
            app.UNO.ButtonPushedFcn = createCallbackFcn(app, @UNOButtonPushed, true);
            app.UNO.Tag = 'UNO';
            app.UNO.Text = '1';
            app.CERO = uibutton(app.FX991LACWPanel, 'push');
            app.CERO.ButtonPushedFcn = createCallbackFcn(app, @CEROButtonPushed, true);
            app.CERO.Tag = 'CERO';
            app.CERO.Text = '0';
            app.CINCO = uibutton(app.FX991LACWPanel, 'push');
            app.CINCO.ButtonPushedFcn = createCallbackFcn(app, @CINCOButtonPushed, true);
            app.CINCO.Tag = 'CINCO';
            app.CINCO.Text = '5';
            app.DOS = uibutton(app.FX991LACWPanel, 'push');
            app.DOS.ButtonPushedFcn = createCallbackFcn(app, @DOSButtonPushed, true);
            app.DOS.Tag = 'DOS';
            app.DOS.Text = '2';
            app.PUNTO = uibutton(app.FX991LACWPanel, 'push');
            app.PUNTO.ButtonPushedFcn = createCallbackFcn(app, @PUNTOButtonPushed, true);
            app.PUNTO.Tag = 'PUNTO';
            app.PUNTO.Text = '.';
            app.SEIS = uibutton(app.FX991LACWPanel, 'push');
            app.SEIS.ButtonPushedFcn = createCallbackFcn(app, @SEISButtonPushed, true);
            app.SEIS.Tag = 'SEIS';
            app.SEIS.Text = '6';
            app.TRES = uibutton(app.FX991LACWPanel, 'push');
            app.TRES.ButtonPushedFcn = createCallbackFcn(app, @TRESButtonPushed, true);
            app.TRES.Tag = 'TRES';
            app.TRES.Text = '3';
            app.MAS = uibutton(app.FX991LACWPanel, 'push');
            app.MAS.ButtonPushedFcn = createCallbackFcn(app, @MASButtonPushed, true);
            app.MAS.Tag = 'MAS';
            app.MAS.Text = '+';
            app.SIETE = uibutton(app.FX991LACWPanel, 'push');
            app.SIETE.ButtonPushedFcn = createCallbackFcn(app, @SIETEButtonPushed, true);
            app.SIETE.Tag = 'SIETE';
            app.SIETE.Text = '7';
            app.OCHO = uibutton(app.FX991LACWPanel, 'push');
            app.OCHO.ButtonPushedFcn = createCallbackFcn(app, @OCHOButtonPushed, true);
            app.OCHO.Tag = 'OCHO';
            app.OCHO.Text = '8';
            app.NUEVE = uibutton(app.FX991LACWPanel, 'push');
            app.NUEVE.ButtonPushedFcn = createCallbackFcn(app, @NUEVEButtonPushed, true);
            app.NUEVE.Tag = 'NUEVE';
            app.NUEVE.Text = '9';
            app.MULTIPLICAR = uibutton(app.FX991LACWPanel, 'push');
            app.MULTIPLICAR.ButtonPushedFcn = createCallbackFcn(app, @MULTIPLICARButtonPushed, true);
            app.MULTIPLICAR.Tag = 'MULTIPLICAR';
            app.MULTIPLICAR.Text = '*';
            app.AC = uibutton(app.FX991LACWPanel, 'push');
            app.AC.ButtonPushedFcn = createCallbackFcn(app, @ACButtonPushed, true);
            app.AC.Tag = 'BORRAR';
            app.AC.Text = 'AC';
            app.DIVIDIR = uibutton(app.FX991LACWPanel, 'push');
            app.DIVIDIR.ButtonPushedFcn = createCallbackFcn(app, @DIVIDIRButtonPushed, true);
            app.DIVIDIR.Tag = 'DIVIDIR';
            app.DIVIDIR.Text = '/';
            app.MENOS = uibutton(app.FX991LACWPanel, 'push');
            app.MENOS.ButtonPushedFcn = createCallbackFcn(app, @MENOSButtonPushed, true);
            app.MENOS.Tag = 'MENOS';
            app.MENOS.Text = '-';
            app.IGUAL = uibutton(app.FX991LACWPanel, 'push');
            app.IGUAL.ButtonPushedFcn = createCallbackFcn(app, @IGUALButtonPushed, true);
            app.IGUAL.Tag = 'IGUAL';
            app.IGUAL.Text = '=';
            app.COMA = uibutton(app.FX991LACWPanel, 'push');
            app.COMA.ButtonPushedFcn = createCallbackFcn(app, @COMAButtonPushed, true);
            app.COMA.Tag = 'COMA';
            app.COMA.Text = ',';
            app.Borrar = uibutton(app.FX991LACWPanel, 'push');
            app.Borrar.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.Borrar.Text = '←';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cientifica_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end