classdef DISTANCIA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        panel                    matlab.ui.container.Panel
        MEDIDATIEMPO             matlab.ui.control.DropDown
        MEDIDAVELOCIDAD          matlab.ui.control.DropDown
        DISTANCIAEditField       matlab.ui.control.EditField
        DISTANCIAEditFieldLabel  matlab.ui.control.Label
        TIEMPOEditField          matlab.ui.control.NumericEditField
        TIEMPOEditFieldLabel     matlab.ui.control.Label
        VELOCIDADEditField       matlab.ui.control.NumericEditField
        VELOCIDADEditFieldLabel  matlab.ui.control.Label
        CALCULAR                 matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULAR
        function CALCULARButtonPushed(app, event)
            velocidad = app.VELOCIDADEditField.Value;
            tiempo = app.TIEMPOEditField.Value;
            
            unidadVelocidad = app.MEDIDAVELOCIDAD.Value;
            unidadTiempo = app.MEDIDATIEMPO.Value;
            
            % Validación de entrada
            if isempty(velocidad) || isempty(tiempo)
                uialert(app.figure1, 'Debe ingresar velocidad y tiempo.', 'Datos faltantes');
                return;
            end
        
            % Convertir velocidad a m/s si es necesario
            if strcmp(unidadVelocidad, 'km/h')
                velocidad = velocidad * (1000 / 3600); % 1 km/h = 1000/3600 m/s
            end
        
            % Convertir tiempo a segundos si es necesario
            if strcmp(unidadTiempo, 'h')
                tiempo = tiempo * 3600; % 1 hora = 3600 s
            elseif strcmp(unidadTiempo, 'min')
                tiempo = tiempo * 60;   % 1 minuto = 60 s
            end
        
            % Calcular distancia en metros
            distancia = velocidad * tiempo;
        
            % Mostrar resultado
            app.DISTANCIAEditField.Value = sprintf('%.2f m', distancia);         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DISTANCIA';
            app.figure1.Tag = 'figure1';
            app.panel = uipanel(app.figure1);
            app.panel.Title = 'CALCULO DE DISTANCIA';
            app.panel.Tag = 'uipanel1';
            app.CALCULAR = uibutton(app.panel, 'push');
            app.CALCULAR.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULAR.Tag = 'pushbutton1';
            app.CALCULAR.Text = 'CALCULAR';
            app.VELOCIDADEditFieldLabel = uilabel(app.panel);
            app.VELOCIDADEditFieldLabel.Text = 'VELOCIDAD: ';
            app.VELOCIDADEditField = uieditfield(app.panel, 'numeric');
            app.TIEMPOEditFieldLabel = uilabel(app.panel);
            app.TIEMPOEditFieldLabel.Text = 'TIEMPO: ';
            app.TIEMPOEditField = uieditfield(app.panel, 'numeric');
            app.DISTANCIAEditFieldLabel = uilabel(app.panel);
            app.DISTANCIAEditFieldLabel.Text = 'DISTANCIA :';
            app.DISTANCIAEditField = uieditfield(app.panel, 'text');
            app.MEDIDAVELOCIDAD = uidropdown(app.panel);
            app.MEDIDAVELOCIDAD.Items = {'km/h', 'm/s'};
            app.MEDIDAVELOCIDAD.Value = 'km/h';
            app.MEDIDATIEMPO = uidropdown(app.panel);
            app.MEDIDATIEMPO.Items = {'s', 'min', 'h'};
            app.MEDIDATIEMPO.Value = 'h';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DISTANCIA_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end