classdef TIEMPO_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        uipanel1                 matlab.ui.container.Panel
        TiempoDropDown           matlab.ui.control.DropDown
        VelocidadDropDown        matlab.ui.control.DropDown
        DistanciaDropDown        matlab.ui.control.DropDown
        TIEMPOEditField          matlab.ui.control.EditField
        TIEMPOEditFieldLabel     matlab.ui.control.Label
        VELOCIDADEditField       matlab.ui.control.NumericEditField
        VELOCIDADEditFieldLabel  matlab.ui.control.Label
        DISTANCIAEditField       matlab.ui.control.NumericEditField
        DISTANCIAEditFieldLabel  matlab.ui.control.Label
        pushbutton1              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pushbutton1
        function pushbutton1ButtonPushed(app, event)
            % Obtener valores ingresados
            distancia = app.DISTANCIAEditField.Value;
            velocidad = app.VELOCIDADEditField.Value;
            unidadDistancia = app.DistanciaDropDown.Value;
            unidadVelocidad = app.VelocidadDropDown.Value;
        
            % Validación básica
            if velocidad <= 0
                uialert(app.figure1, 'La velocidad debe ser mayor que cero.', 'Error');
                return;
            end
        
            % Conversión de distancia a metros
            switch unidadDistancia
                case 'km'
                    distancia = distancia * 1000; % km → m
                % Si es 'm', no se hace nada
            end
        
            % Conversión de velocidad a m/s
            switch unidadVelocidad
                case 'km/h'
                    velocidad = velocidad * 1000 / 3600; % km/h → m/s
                % Si es 'm/s', no se hace nada
            end
        
            % Calcular tiempo en segundos
            tiempo = distancia / velocidad;

            % Obtener unidad de tiempo seleccionada
            unidadTiempo = app.TiempoDropDown.Value;
            
            % Convertir el tiempo según la unidad elegida
            switch unidadTiempo
                case 'min'
                    tiempo = tiempo / 60;
                    sufijo = 'min';
                case 'h'
                    tiempo = tiempo / 3600;
                    sufijo = 'h';
                otherwise
                    sufijo = 's';
            end
            
            % Mostrar el resultado formateado
            app.TIEMPOEditField.Value = sprintf('%.2f %s', tiempo, sufijo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TIEMPO';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'CALCULO DE TIEMPO';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1ButtonPushed, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'CALCULAR';
            app.DISTANCIAEditFieldLabel = uilabel(app.uipanel1);
            app.DISTANCIAEditFieldLabel.Text = 'DISTANCIA: ';
            app.DISTANCIAEditField = uieditfield(app.uipanel1, 'numeric');
            app.VELOCIDADEditFieldLabel = uilabel(app.uipanel1);
            app.VELOCIDADEditFieldLabel.Text = 'VELOCIDAD:';
            app.VELOCIDADEditField = uieditfield(app.uipanel1, 'numeric');
            app.TIEMPOEditFieldLabel = uilabel(app.uipanel1);
            app.TIEMPOEditFieldLabel.Text = 'TIEMPO: ';
            app.TIEMPOEditField = uieditfield(app.uipanel1, 'text');
            app.DistanciaDropDown = uidropdown(app.uipanel1);
            app.DistanciaDropDown.Items = {'m', 'km'};
            app.DistanciaDropDown.Value = 'm';
            app.VelocidadDropDown = uidropdown(app.uipanel1);
            app.VelocidadDropDown.Items = {'m/s', 'km/h'};
            app.VelocidadDropDown.Value = 'm/s';
            app.TiempoDropDown = uidropdown(app.uipanel1);
            app.TiempoDropDown.Items = {'s', 'min', 'h'};
            app.TiempoDropDown.Value = 's';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TIEMPO_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end